classdef JSmolSelectionDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZEALUIFigure                  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        InstructionsPanel             matlab.ui.container.Panel
        SelectdeselectLabel           matlab.ui.control.Label
        DeselectallwithinboxLabel     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        HoldSHIFTLabel                matlab.ui.control.Label
        HoldSHIFTALTSHIFTOPTIONonMacLabel  matlab.ui.control.Label
        SelectionPanel                matlab.ui.container.Panel
        invertButton                  matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        StructurerepresentationPanel  matlab.ui.container.Panel
        CartoonButton                 matlab.ui.control.StateButton
        BallStickButton               matlab.ui.control.StateButton
        RightPanel                    matlab.ui.container.Panel
        JSmolWindowPanel              matlab.ui.container.Panel
        HTML                          matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        JSmol
        callingApp
        win
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, pdbJSmolMatStruct, JSmolLibPath, scriptStr, structureSel)
            warning off Matlab:structOnObject 
            
            app.HTML.Data = pdbJSmolMatStruct;
            
            app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmol_matlabDataTransfer.htm');
                        
            app.callingApp = callingApp;
            app.JSmol.scriptStr = scriptStr;
            app.JSmol.structureSel = structureSel;
                        
            pause(1);
            
            figProps = struct(app.ZEALUIFigure);
            controller = figProps.Controller;
            controllerProps = struct(controller);
            platformHost = controllerProps.PlatformHost;
            platformHostProps = struct(platformHost);
            app.win = platformHostProps.CEF;
            
            pause(1);
            % Get the window object of the iframe (uihtml container )
            app.win.executeJS('var x = document.getElementsByTagName("iframe")[0].contentWindow;');
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', scriptStr);
            
            fprintf('\n JScommand: %s', JScommand);
            app.win.executeJS(JScommand);
            
            BallStickButtonValueChanged(app);
            
        end

        % Button pushed function: invertButton
        function invertButtonPushed(app, event)
                               
            app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            
        end

        % Value changed function: BallStickButton
        function BallStickButtonValueChanged(app, event)
           
            if app.BallStickButton.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill reset; wireframe reset")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill reset; wireframe reset")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill off; wireframe off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill off; wireframe off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ZEALUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {558, 558};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {277, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: CartoonButton
        function CartoonButtonValueChanged(app, event)
                       
            if app.CartoonButton.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon on")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon on")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            end
        end

        % Callback function
        function SaveButtonPushed(app, event)
         
            if strcmp(app.JSmol.structureSel, 'rotating')
                defaultPdbpath=fullfile(app.callingApp.paths.tmp,'ROIatmIndex_rot.txt');               
            else
                defaultPdbpath=fullfile(app.callingApp.paths.tmp,'ROIatmIndex_fix.txt');
            end
            
            if isdeployed
                [filename, pathname] = uiputfile('*', 'Save ROI', defaultPdbpath);
                pdbpath = fullfile(pathname, filename);
            else
                pdbpath = defaultPdbpath;
            end
                        
            app.callingApp.paths.ROIpdbpath = pdbpath;
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"x={selected}; write VAR x %s")'')', pdbpath);
            app.win.executeJS(JScommand);
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            delete(app);
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
         
            nAtoms = length(data);            
            selIndex = (zeros(nAtoms,1));
            
            fprintf('\n %d atoms selected.\nParsing JSmol putput...', nAtoms);
            
            try
            if iscell(data)
                for i = 1:nAtoms
                    selIndex(i) = (data{i}.atomIndex+1);
                end
            elseif isstruct(data)
                for i = 1:nAtoms
                    selIndex(i) = data(i).atomIndex+1;
                end                
            end
            
            catch
                error('\n Unkown HTML data from JSmol.\n');
            end
            fprintf('done.\n');                  
          
            importSelection(app.callingApp,selIndex);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZEALUIFigure = uifigure('Visible', 'off');
            app.ZEALUIFigure.Name = 'ZEAL';
            app.ZEALUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ZEALUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InstructionsPanel = uipanel(app.LeftPanel);
            app.InstructionsPanel.Title = 'Instructions';
            app.SelectdeselectLabel = uilabel(app.InstructionsPanel);
            app.SelectdeselectLabel.Text = {'Select/'; 'deselect'};
            app.DeselectallwithinboxLabel = uilabel(app.InstructionsPanel);
            app.DeselectallwithinboxLabel.Text = {'Deselect all'; 'within box'};
            app.Label = uilabel(app.InstructionsPanel);
            app.Label.Text = {'Click-and-drag inside the JSmol window '; 'while pressing the select/deselct keys '; 'defined below.'};
            app.HoldSHIFTLabel = uilabel(app.InstructionsPanel);
            app.HoldSHIFTLabel.Text = 'Hold SHIFT';
            app.HoldSHIFTALTSHIFTOPTIONonMacLabel = uilabel(app.InstructionsPanel);
            app.HoldSHIFTALTSHIFTOPTIONonMacLabel.Text = {'Hold SHIFT+ALT'; '(SHIFT+OPTION on Mac)'};
            app.SelectionPanel = uipanel(app.LeftPanel);
            app.SelectionPanel.Title = 'Selection';
            app.invertButton = uibutton(app.SelectionPanel, 'push');
            app.invertButton.ButtonPushedFcn = createCallbackFcn(app, @invertButtonPushed, true);
            app.invertButton.Text = 'invert';
            app.DoneButton = uibutton(app.LeftPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.StructurerepresentationPanel = uipanel(app.LeftPanel);
            app.StructurerepresentationPanel.Title = 'Structure representation';
            app.CartoonButton = uibutton(app.StructurerepresentationPanel, 'state');
            app.CartoonButton.ValueChangedFcn = createCallbackFcn(app, @CartoonButtonValueChanged, true);
            app.CartoonButton.Text = 'Cartoon';
            app.BallStickButton = uibutton(app.StructurerepresentationPanel, 'state');
            app.BallStickButton.ValueChangedFcn = createCallbackFcn(app, @BallStickButtonValueChanged, true);
            app.BallStickButton.Text = 'Ball&Stick';
            app.BallStickButton.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.JSmolWindowPanel = uipanel(app.RightPanel);
            app.JSmolWindowPanel.Title = 'JSmol Window';
            app.HTML = uihtml(app.JSmolWindowPanel);
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.ZEALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JSmolSelectionDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZEALUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZEALUIFigure)
        end
    end
end